classdef Traffic_Light < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ModeOfOperationSwitch       matlab.ui.control.Switch
        ModeOfOperationSwitchLabel  matlab.ui.control.Label
        Red_Led                     matlab.ui.control.Lamp
        Yellow_Led                  matlab.ui.control.Lamp
        Green_Led                   matlab.ui.control.Lamp
        Label                       matlab.ui.control.Label
        ManualModePanel             matlab.ui.container.Panel
        GreenButton                 matlab.ui.control.Button
        YellowButton                matlab.ui.control.Button
        REDButton                   matlab.ui.control.Button
        AutomaticModePanel          matlab.ui.container.Panel
        Automatic_Switch            matlab.ui.control.Switch
        DelayTimeEditField          matlab.ui.control.NumericEditField
        DelayTimeEditFieldLabel     matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global a_uno;
            global red_pin;
            global yellow_pin;
            global green_pin;
            red_pin = "D13";
            yellow_pin = "D12";
            green_pin = "D11";
            a_uno = arduino("COM11","Uno");
            configurePin(a_uno,red_pin,'DigitalOutput');
            configurePin(a_uno,yellow_pin,'DigitalOutput');
            configurePin(a_uno,green_pin,'DigitalOutput');
            
            set(app.Red_Led, 'Enable', 'off');
            set(app.Yellow_Led, 'Enable', 'off');
            set(app.Green_Led, 'Enable', 'off');
            if (app.ModeOfOperationSwitch.Value =="Auto")
                set(app.ManualModePanel, 'Enable', 'off');
            else
                set(app.AutomaticModePanel, 'Enable', 'off');
            end


        end

        % Value changed function: DelayTimeEditField
        function DelayTimeEditFieldValueChanged(app, event)
           global Timecountdown ;
           Timecountdown = app.DelayTimeEditField.Value;
           app.Label.Text= num2str(Timecountdown);
        end

        % Value changed function: Automatic_Switch
        function Automatic_SwitchValueChanged(app, event)
            global a_uno;
            global red_pin;
            global yellow_pin;
            global green_pin;
            global Timecountdown ;
            global ProcessInTime ;
            while(app.Automatic_Switch.Value == "Start" && app.ModeOfOperationSwitch.Value== "Auto" )
              ProcessInTime= Timecountdown ;
              while (ProcessInTime~=0)
              if (app.Automatic_Switch.Value == "Stop" || app.ModeOfOperationSwitch.Value== "Manual")
                  app.Label.Text="0";
                  break;
              end
              app.Label.Text= num2str(ProcessInTime);
              set(app.Green_Led, 'Enable', 'off');
              writeDigitalPin(a_uno,green_pin,0);
              set(app.Red_Led, 'Enable', 'on');
              writeDigitalPin(a_uno,red_pin,1);
              pause(1);
              ProcessInTime = ProcessInTime-1;
              end
              
              ProcessInTime= Timecountdown ;
              while (ProcessInTime~=0)
              if (app.Automatic_Switch.Value == "Stop" || app.ModeOfOperationSwitch.Value== "Manual")
                  app.Label.Text="0";
                  break;
              end
               app.Label.Text= num2str(ProcessInTime);
              set(app.Red_Led, 'Enable', 'off');
              writeDigitalPin(a_uno,red_pin,0);
              set(app.Yellow_Led, 'Enable', 'on');
              writeDigitalPin(a_uno,yellow_pin,1);
              pause(1);
              ProcessInTime = ProcessInTime-1;
              end
              
              ProcessInTime= Timecountdown ;
              while (ProcessInTime~=0)
              if (app.Automatic_Switch.Value == "Stop" || app.ModeOfOperationSwitch.Value== "Manual")
                  app.Label.Text="0";
                  break;
              end
               app.Label.Text= num2str(ProcessInTime);
              set(app.Yellow_Led, 'Enable', 'off');
              writeDigitalPin(a_uno,yellow_pin,0);
              set(app.Green_Led, 'Enable', 'on');
              writeDigitalPin(a_uno,green_pin,1);
              pause(1);
              ProcessInTime = ProcessInTime-1;
              end
           end
            
        end

        % Value changed function: ModeOfOperationSwitch
        function ModeOfOperationSwitchValueChanged(app, event)
            global a_uno;
            global red_pin;
            global yellow_pin;
            global green_pin;
            if (app.ModeOfOperationSwitch.Value =="Auto")
                set(app.Red_Led, 'Enable', 'off');
                set(app.Yellow_Led, 'Enable', 'off');
                set(app.Green_Led, 'Enable', 'off');
                set(app.ManualModePanel, 'Enable', 'off');
                set(app.AutomaticModePanel, 'Enable', 'on');
                writeDigitalPin(a_uno,green_pin,0);
                writeDigitalPin(a_uno,red_pin,0);
                writeDigitalPin(a_uno,yellow_pin,0);
            else
                app.Automatic_Switch.Value="Stop";
                set(app.Red_Led, 'Enable', 'off');
                set(app.Yellow_Led, 'Enable', 'off');
                set(app.Green_Led, 'Enable', 'off');
                set(app.AutomaticModePanel, 'Enable', 'off');
                set(app.ManualModePanel, 'Enable', 'on');
                writeDigitalPin(a_uno,green_pin,0);
                writeDigitalPin(a_uno,red_pin,0);
                writeDigitalPin(a_uno,yellow_pin,0);
            end
        end

        % Button pushed function: REDButton
        function REDButtonPushed(app, event)
            global a_uno;
            global red_pin;
            global yellow_pin;
            global green_pin;
            set(app.Red_Led, 'Enable', 'on');
            set(app.Yellow_Led, 'Enable', 'off');
            set(app.Green_Led, 'Enable', 'off');
            writeDigitalPin(a_uno,green_pin,0);
            writeDigitalPin(a_uno,red_pin,1);
            writeDigitalPin(a_uno,yellow_pin,0);
        end

        % Button pushed function: YellowButton
        function YellowButtonPushed(app, event)
            global a_uno;
            global red_pin;
            global yellow_pin;
            global green_pin;
            writeDigitalPin(a_uno,green_pin,0);
            writeDigitalPin(a_uno,red_pin,0);
            writeDigitalPin(a_uno,yellow_pin,1);
            set(app.Red_Led, 'Enable', 'off');
            set(app.Yellow_Led, 'Enable', 'on');
            set(app.Green_Led, 'Enable', 'off');
        end

        % Button pushed function: GreenButton
        function GreenButtonPushed(app, event)
            global a_uno;
            global red_pin;
            global yellow_pin;
            global green_pin;
            writeDigitalPin(a_uno,green_pin,1);
            writeDigitalPin(a_uno,red_pin,0);
            writeDigitalPin(a_uno,yellow_pin,0);
            set(app.Red_Led, 'Enable', 'off');
            set(app.Yellow_Led, 'Enable', 'off');
            set(app.Green_Led, 'Enable', 'on');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AutomaticModePanel = uipanel(app.UIFigure);
            app.AutomaticModePanel.Title = 'Automatic Mode';
            app.DelayTimeEditFieldLabel = uilabel(app.AutomaticModePanel);
            app.DelayTimeEditFieldLabel.Text = 'Delay Time';
            app.DelayTimeEditField = uieditfield(app.AutomaticModePanel, 'numeric');
            app.DelayTimeEditField.ValueChangedFcn = createCallbackFcn(app, @DelayTimeEditFieldValueChanged, true);
            app.Automatic_Switch = uiswitch(app.AutomaticModePanel, 'slider');
            app.Automatic_Switch.Items = {'Stop', 'Start'};
            app.Automatic_Switch.ValueChangedFcn = createCallbackFcn(app, @Automatic_SwitchValueChanged, true);
            app.Automatic_Switch.Value = 'Stop';
            app.ManualModePanel = uipanel(app.UIFigure);
            app.ManualModePanel.Title = 'Manual Mode';
            app.REDButton = uibutton(app.ManualModePanel, 'push');
            app.REDButton.ButtonPushedFcn = createCallbackFcn(app, @REDButtonPushed, true);
            app.REDButton.Text = 'RED';
            app.YellowButton = uibutton(app.ManualModePanel, 'push');
            app.YellowButton.ButtonPushedFcn = createCallbackFcn(app, @YellowButtonPushed, true);
            app.YellowButton.Text = 'Yellow';
            app.GreenButton = uibutton(app.ManualModePanel, 'push');
            app.GreenButton.ButtonPushedFcn = createCallbackFcn(app, @GreenButtonPushed, true);
            app.GreenButton.Text = 'Green';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '0';
            app.Green_Led = uilamp(app.UIFigure);
            app.Yellow_Led = uilamp(app.UIFigure);
            app.Red_Led = uilamp(app.UIFigure);
            app.ModeOfOperationSwitchLabel = uilabel(app.UIFigure);
            app.ModeOfOperationSwitchLabel.Text = 'Mode Of Operation';
            app.ModeOfOperationSwitch = uiswitch(app.UIFigure, 'slider');
            app.ModeOfOperationSwitch.Items = {'Auto', 'Manual'};
            app.ModeOfOperationSwitch.ValueChangedFcn = createCallbackFcn(app, @ModeOfOperationSwitchValueChanged, true);
            app.ModeOfOperationSwitch.Value = 'Auto';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Traffic_Light

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end