classdef Calculatorv1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        matrix2               matlab.ui.control.EditField
        matrix1               matlab.ui.control.EditField
        resultmat             matlab.ui.control.TextArea
        DeleteButton          matlab.ui.control.Button
        EnterButton           matlab.ui.control.Button
        Error_11              matlab.ui.control.TextArea
        plotingoperationmenu  matlab.ui.control.ListBox
        PlottingbuttongroupListBoxLabel  matlab.ui.control.Label
        Equationtoplot        matlab.ui.control.TextArea
        Error_9               matlab.ui.control.TextArea
        EqualButton_2         matlab.ui.control.Button
        Errorc2               matlab.ui.control.TextArea
        Error_7               matlab.ui.control.TextArea
        operation_2           matlab.ui.control.DropDown
        matrixopeation        matlab.ui.control.TextArea
        labely                matlab.ui.control.TextArea
        labelx                matlab.ui.control.TextArea
        EqualButton           matlab.ui.control.Button
        operation             matlab.ui.control.DropDown
        TextArea_5            matlab.ui.control.TextArea
        AOC1                  matlab.ui.control.TextArea
        TextArea_3            matlab.ui.control.TextArea
        resultc1              matlab.ui.control.NumericEditField
        Number2               matlab.ui.control.NumericEditField
        Number1               matlab.ui.control.NumericEditField
        Errorc1               matlab.ui.control.TextArea
        TextArea              matlab.ui.control.TextArea
        Plotinggrapgh         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EqualButton
        function EqualButtonPushed(app, event)
            Equal=app.operation.Value;
            switch Equal
                case '+'
                    app.AOC1.Value = '+';
                   app.resultc1.Value=  app.Number1.Value + app.Number2.Value;
                case '-'
                    app.AOC1.Value = '-';
                    app.resultc1.Value=  app.Number1.Value - app.Number2.Value;
                case '/'
                    app.AOC1.Value = '/';
                    if app.Number2.Value == 0 
                        app.Errorc1.Value = 'Can not devide by Zero';
                    else
                       app.resultc1.Value=  app.Number1.Value / app.Number2.Value;
                    end
                case '*'
                    app.AOC1.Value = '*';
                    app.resultc1.Value=  app.Number1.Value * app.Number2.Value;
            end
        end

        % Button pushed function: EqualButton_2
        function EqualButton_2Pushed(app, event)
        strMatrix1 = app.matrix1.Value;
        strMatrix2 = app.matrix2.Value;
        mat1 = eval(strMatrix1);
        mat2 = eval(strMatrix2);
        rowsmat1 = size(mat1, 1);
        colmat1 = size(mat1, 2);
        rowsmat2 = size(mat2, 1);
        colmat2 = size(mat2, 2);
if ismatrix(mat1) && ismatrix(mat2)
    matoperation = app.operation_2.Value;
    switch matoperation
        case '+'
            if (rowsmat1 == rowsmat2) && (colmat1 == colmat2)
                app.resultmat.Value = mat1 + mat2 ;
            else
                app.Errorc2.Value = 'Wrong number of rows or columns; matrices must have the same dimensions.';
            end
    end
else
    app.Errorc2.Value = 'Wrong configuration for Mat1 or Mat2';
end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Plotinggrapgh = uiaxes(app.UIFigure);
            title(app.Plotinggrapgh, 'Title')
            zlabel(app.Plotinggrapgh, 'Z')
            app.Plotinggrapgh.YLim = [0 2];
            app.Plotinggrapgh.XTickLabel = {'1'; '1.5'; '2'; '2.5'; '3'};
            app.Plotinggrapgh.YTickLabel = {'2'; '3'; '4'; '5'; '6'};
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Error console'};
            app.Errorc1 = uitextarea(app.UIFigure);
            app.Number1 = uieditfield(app.UIFigure, 'numeric');
            app.Number2 = uieditfield(app.UIFigure, 'numeric');
            app.resultc1 = uieditfield(app.UIFigure, 'numeric');
            app.TextArea_3 = uitextarea(app.UIFigure);
            app.TextArea_3.Value = {'='};
            app.AOC1 = uitextarea(app.UIFigure);
            app.TextArea_5 = uitextarea(app.UIFigure);
            app.TextArea_5.Value = {'Arithmetic Operation'};
            app.operation = uidropdown(app.UIFigure);
            app.operation.Items = {'+', '-', '*', '/'};
            app.operation.Value = '+';
            app.EqualButton = uibutton(app.UIFigure, 'push');
            app.EqualButton.ButtonPushedFcn = createCallbackFcn(app, @EqualButtonPushed, true);
            app.EqualButton.Text = 'Equal';
            app.labelx = uitextarea(app.UIFigure);
            app.labelx.Value = {'x_axis'};
            app.labely = uitextarea(app.UIFigure);
            app.labely.Value = {'y_axis'};
            app.matrixopeation = uitextarea(app.UIFigure);
            app.operation_2 = uidropdown(app.UIFigure);
            app.operation_2.Items = {'+', '-', '*', '/'};
            app.operation_2.Value = '+';
            app.Error_7 = uitextarea(app.UIFigure);
            app.Error_7.Value = {'Error console'};
            app.Errorc2 = uitextarea(app.UIFigure);
            app.EqualButton_2 = uibutton(app.UIFigure, 'push');
            app.EqualButton_2.ButtonPushedFcn = createCallbackFcn(app, @EqualButton_2Pushed, true);
            app.EqualButton_2.Text = 'Equal';
            app.Error_9 = uitextarea(app.UIFigure);
            app.Error_9.Value = {'Enter equation fun(x) or y(x)'};
            app.Equationtoplot = uitextarea(app.UIFigure);
            app.PlottingbuttongroupListBoxLabel = uilabel(app.UIFigure);
            app.PlottingbuttongroupListBoxLabel.Text = 'Plotting button group';
            app.plotingoperationmenu = uilistbox(app.UIFigure);
            app.plotingoperationmenu.Items = {'x axis', 'y axis', 'x y axis', 'resultant', 'sin', 'cos', 'equation'};
            app.plotingoperationmenu.Value = 'x axis';
            app.Error_11 = uitextarea(app.UIFigure);
            app.Error_11.Value = {'Matrix Area'};
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.Text = 'Enter';
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.Text = 'Delete';
            app.resultmat = uitextarea(app.UIFigure);
            app.matrix1 = uieditfield(app.UIFigure, 'text');
            app.matrix2 = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Calculatorv1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end